classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        saveimage                 matlab.ui.control.Button
        TabGroup2                 matlab.ui.container.TabGroup
        Tab1                      matlab.ui.container.Tab
        LogButton_2               matlab.ui.control.Button
        GammaButton_2             matlab.ui.control.Button
        LogTextArea               matlab.ui.control.TextArea
        TextAreaLabel             matlab.ui.control.Label
        GammaTextArea             matlab.ui.control.TextArea
        GammaTextAreaLabel        matlab.ui.control.Label
        LogSlider                 matlab.ui.control.Slider
        LogButton                 matlab.ui.control.Button
        GammaSlider               matlab.ui.control.Slider
        GammaButton               matlab.ui.control.Button
        Tab2                      matlab.ui.container.Tab
        DoitButton2_2             matlab.ui.control.Button
        Button3                   matlab.ui.control.Button
        Slider2                   matlab.ui.control.Slider
        TextArea2                 matlab.ui.control.TextArea
        Label                     matlab.ui.control.Label
        DoitButton2               matlab.ui.control.Button
        Button1                   matlab.ui.control.Button
        Tab3                      matlab.ui.container.Tab
        Button3_2                 matlab.ui.control.Button
        bSlider                   matlab.ui.control.Slider
        bSliderLabel              matlab.ui.control.Label
        gSlider                   matlab.ui.control.Slider
        gSliderLabel              matlab.ui.control.Label
        rSlider                   matlab.ui.control.Slider
        rSliderLabel              matlab.ui.control.Label
        Button3_1                 matlab.ui.control.Button
        Tab                       matlab.ui.container.Tab
        Button4_7                 matlab.ui.control.Button
        Button4_6                 matlab.ui.control.Button
        TextArea                  matlab.ui.control.TextArea
        Button4_5                 matlab.ui.control.Button
        Button4_4                 matlab.ui.control.Button
        Button4_3                 matlab.ui.control.Button
        Button4_2                 matlab.ui.control.Button
        sigmaEditField            matlab.ui.control.NumericEditField
        sigmaEditFieldLabel       matlab.ui.control.Label
        kernelSizeEditField       matlab.ui.control.NumericEditField
        kernelSizeEditFieldLabel  matlab.ui.control.Label
        Button4_1                 matlab.ui.control.Button
        Tab_2                     matlab.ui.container.Tab
        lowpassfilterButton       matlab.ui.control.Button
        Tab_3                     matlab.ui.container.Tab
        Button_2                  matlab.ui.control.Button
        houghtransformbutton      matlab.ui.control.Button
        DoitButton_2              matlab.ui.control.Button
        Button                    matlab.ui.control.Button
        clear_img                 matlab.ui.control.StateButton
        import_img                matlab.ui.control.StateButton
        TabGroup                  matlab.ui.container.TabGroup
        channel1Tab               matlab.ui.container.Tab
        channel1im                matlab.ui.control.UIAxes
        channel2Tab               matlab.ui.container.Tab
        channel2im                matlab.ui.control.UIAxes
        channel3Tab               matlab.ui.container.Tab
        channel3im                matlab.ui.control.UIAxes
        ButtonGroup               matlab.ui.container.ButtonGroup
        channel3Button            matlab.ui.control.RadioButton
        channel2Button            matlab.ui.control.RadioButton
        channel1Button            matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        images1 % Description
        images2 % Description
        images3 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: channel1im, import_img
        function import_imgValueChanged(app, event)
            
            [f,p]=uigetfile({'*.jpg;*.tif;*.png;*.gif;*.bmp','All Image Files'});
             if (ischar(p))
               fname = [p f];
               im=imread(fname);%读取图片，图片名字为im
               [ima,channel] =channelchoice(app);%%同文件下的channel函数，可以根据channel的返回不同的channel区
               %%根据不同的channel选择进行操作
               imshow(im,'Parent',channel);
               %将读取到的图片和对应的全局变量绑定
               if(channel==app.channel1im)
                   app.images1=im;
               elseif(channel==app.channel2im)
                   app.images2=im;
               elseif(channel==app.channel3im)
                   app.images3=im;
               end
             end
        end

        % Value changed function: clear_img
        function clear_imgValueChanged(app, event)
            [im,channel] = channelchoice(app);
           if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
           end
            cla(channel);
            blackimage =ones(100,100);
            im = blackimage;
           if(channel==app.channel1im)
               app.images1=im;
           elseif(channel==app.channel2im)
               app.images2=im;
           elseif(channel==app.channel3im)
               app.images3=im;
           end
        end

        % Button pushed function: saveimage
        function saveimageButtonPushed(app, event)
           % 让用户选择一个目录
        [im,channel] = channelchoice(app); 
        % 获取axes中的所有children
        children = channel.Children;
        
        % 检查children中是否存在Image类型的对象
        hasImage = any(arrayfun(@(x) isa(x, 'matlab.graphics.primitive.Image'), children));
        
        if hasImage
           imsave(channel);
        else
            blackimage =ones(100,100);
            im = blackimage;
            imshow(blackimage,'Parent',channel);
            imsave(channel);
        end

        % 确保用户没有点击取消

        end

        % Callback function
        function compareButtonPushed(app, event)
    
            [im,channel] = channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
            elseif(channel==app.channel2im)
               im=app.images2;
            elseif(channel==app.channel3im)
               im=app.images3;
            end        
            im = im.^2;
            imshow(im,'Parent',channel);
            disp(im);
           if(channel==app.channel1im)
               app.images1=im;
           elseif(channel==app.channel2im)
               app.images2=im;
           elseif(channel==app.channel3im)
               app.images3=im;
           end
        end

        % Value changed function: GammaTextArea
        function GammaTextAreaValueChanged(app, event)
      
            app.GammaTextArea.Value=string(app.Gammaslider);
        end

        % Value changed function: GammaSlider
        function GammaSliderValueChanged(app, event)
            value = app.GammaSlider.Value;
     app.GammaTextArea.Value=string(value);
    
        end

        % Button pushed function: GammaButton
        function GammaButtonPushed(app, event)
            %进行Gamma变换（使用滑块的参数）
            gamma=app.GammaSlider.Value;
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end        
           im= gamma_transform(im,gamma);
           %显示新的图像矩阵
           imshow(im,'Parent',channel);
        end

        % Value changed function: LogSlider
        function LogSliderValueChanged(app, event)
            value = app.LogSlider.Value;
            app.LogTextArea.Value=string(value);
        end

        % Button pushed function: LogButton
        function LogButtonPushed(app, event)
            %进行对数变换，使用滑块的参数
            c=app.LogSlider.Value;
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end        
           im= logadjustContrast(im,c);
           %显示新的图像矩阵
            imshow(im,'Parent',channel);
        end

        % Callback function
        function Button3Pushed(app, event)

        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
           %进行图像求补
             [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end        
           im= incomplete(im);
           %显示新的图像矩阵
           imshow(im,'Parent',channel);
           if(channel==app.channel1im)
               app.images1=im;
           elseif(channel==app.channel2im)
               app.images2=im;
           elseif(channel==app.channel3im)
               app.images3=im;
           end
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            value = app.Slider2.Value;
            app.TextArea2.Value=string(value);
        end

        % Button pushed function: DoitButton2
        function DoitButton2Pushed(app, event)
            %进行增亮
            l=app.Slider2.Value;
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end        
           im= increaseBrightness(im,l);
           %显示新的图像矩阵
           imshow(im,'Parent',channel);           
        end

        % Button pushed function: Button3
        function Button3Pushed2(app, event)
            %转换为xyz颜色变换
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end        
           im= xyz_transform(im);
           %显示新的图像矩阵
           imshow(im,'Parent',channel);
           if(channel==app.channel1im)
               app.images1=im;
           elseif(channel==app.channel2im)
               app.images2=im;
           elseif(channel==app.channel3im)
               app.images3=im;
           end
        end

        % Button pushed function: Button3_1
        function Button3_1Pushed(app, event)
            %色彩平衡
             r=app.rSlider.Value;
             g=app.gSlider.Value;
             b=app.bSlider.Value;
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end        
           im= color_enhance(im,r,g,b);
           %显示新的图像矩阵
           imshow(im,'Parent',channel);
        end

        % Button pushed function: Button4_1
        function Button4_1Pushed(app, event)
            %根据两个参数进行高斯滤波  
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end        
           im= gaussianFilter(im,app.kernelSizeEditField.Value,app.sigmaEditField.Value);
           %显示新的图像矩阵
           imshow(im,'Parent',channel);
           if(channel==app.channel1im)
               app.images1=im;
           elseif(channel==app.channel2im)
               app.images2=im;
           elseif(channel==app.channel3im)
               app.images3=im;
           end
        end

        % Button pushed function: Button4_2
        function Button4_2Pushed(app, event)
            %进行中值滤波
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end        
           im= medianFilter(im);
           %显示新的图像矩阵
           imshow(im,'Parent',channel);
           if(channel==app.channel1im)
               app.images1=im;
           elseif(channel==app.channel2im)
               app.images2=im;
           elseif(channel==app.channel3im)
               app.images3=im;
           end
        end

        % Button pushed function: Button4_3
        function Button4_3Pushed(app, event)
            %进行拉普拉斯滤波
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end   
           im= laplacian_filter(im);
           %显示新的图像矩阵
           imshow(im,'Parent',channel);
           if(channel==app.channel1im)
               app.images1=im;
           elseif(channel==app.channel2im)
               app.images2=im;
           elseif(channel==app.channel3im)
               app.images3=im;
           end
        end

        % Button pushed function: Button4_4
        function Button4_4Pushed(app, event)
             %进行高斯金字塔变换
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end   
           im= gaussian_pyramid(im);
           %显示新的图像矩阵
           imshow(im,'Parent',channel);
        end

        % Button pushed function: Button4_5
        function Button4_5Pushed(app, event)
             %进行Box滤波，shift类型
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end   
           im= boxFilter(im);
           %显示新的图像矩阵
           imshow(im,'Parent',channel);
           if(channel==app.channel1im)
               app.images1=im;
           elseif(channel==app.channel2im)
               app.images2=im;
           elseif(channel==app.channel3im)
               app.images3=im;
           end
        end

        % Button pushed function: Button4_6
        function Button4_6Pushed(app, event)
             %进行Box滤波，sharpen类型
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end   
           im= boxFilter_sharpen(im);
           %显示新的图像矩阵
           imshow(im,'Parent',channel);
           if(channel==app.channel1im)
               app.images1=im;
           elseif(channel==app.channel2im)
               app.images2=im;
           elseif(channel==app.channel3im)
               app.images3=im;
           end
        end

        % Button pushed function: Button4_7
        function Button4_7Pushed(app, event)
             %进行Box滤波，sobel类型
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end   
           im= boxFilter_sobel_edge(im);
           %显示新的图像矩阵
           imshow(im,'Parent',channel);
           if(channel==app.channel1im)
               app.images1=im;
           elseif(channel==app.channel2im)
               app.images2=im;
           elseif(channel==app.channel3im)
               app.images3=im;
           end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %进行灰度转化
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end   
           im= colortogray(im);
           %显示新的图像矩阵
           imshow(im,'Parent',channel);
        end

        % Button pushed function: DoitButton_2
        function DoitButton_2Pushed(app, event)
            %进行直方图均衡化
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end   
           im= histogramEqualization(im);
           %显示新的图像矩阵
           imshow(im,'Parent',channel);
           if(channel==app.channel1im)
               app.images1=im;
           elseif(channel==app.channel2im)
               app.images2=im;
           elseif(channel==app.channel3im)
               app.images3=im;
           end
        end

        % Button pushed function: lowpassfilterButton
        function lowpassfilterButtonPushed(app, event)
            [im,channel] = channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end   
            im = lowpassfilter(im);
            imshow(im,'Parent',channel);
        end

        % Button pushed function: houghtransformbutton
        function houghtransformbuttonButtonPushed(app, event)
            [RGB,channel] = channelchoice(app);
            if(channel==app.channel1im)
               RGB=app.images1;
           elseif(channel==app.channel2im)
               RGB=app.images2;
           elseif(channel==app.channel3im)
               RGB=app.images3;
            end   
            I  = im2gray(RGB);
            %%Extract edges.
            
            BW = edge(I,'canny');
            %%Calculate Hough transform
            [H,T,R] = hough(BW,'RhoResolution',0.5,'Theta',-90:0.5:89);
            %%Display the original image and the Hough matrix.
            
            
            imshow(imadjust(rescale(H)),'XData',T,'YData',R,...
                  'InitialMagnification','fit','Parent',channel);
            title(channel,"Hough transform");
            xlabel(channel,'\theta');
            ylabel(channel,'\rho')
            axis(channel,'normal');
            axis(channel,'on')
            hold(channel,"on");
            colormap(channel,"hot");
        end

        % Button pushed function: GammaButton_2
        function GammaButton_2Pushed(app, event)
             %进行Gamma变换（使用滑块的参数）
            gamma=app.GammaSlider.Value;
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end        
           im= gamma_transform(im,gamma);
           %显示新的图像矩阵
           imshow(im,'Parent',channel);
           if(channel==app.channel1im)
               app.images1=im;
           elseif(channel==app.channel2im)
               app.images2=im;
           elseif(channel==app.channel3im)
               app.images3=im;
           end
        end

        % Button pushed function: LogButton_2
        function LogButton_2Pushed(app, event)
            %进行对数变换，使用滑块的参数
            c=app.LogSlider.Value;
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end        
           im= logadjustContrast(im,c);
           %显示新的图像矩阵
            imshow(im,'Parent',channel);
           if(channel==app.channel1im)
               app.images1=im;
           elseif(channel==app.channel2im)
               app.images2=im;
           elseif(channel==app.channel3im)
               app.images3=im;
           end
        end

        % Button pushed function: DoitButton2_2
        function DoitButton2_2Pushed(app, event)
                        %进行增亮
            l=app.Slider2.Value;
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end        
           im= increaseBrightness(im,l);
           %显示新的图像矩阵
           imshow(im,'Parent',channel);      
           if(channel==app.channel1im)
               app.images1=im;
           elseif(channel==app.channel2im)
               app.images2=im;
           elseif(channel==app.channel3im)
               app.images3=im;
           end
        end

        % Button pushed function: Button3_2
        function Button3_2Pushed(app, event)
            %色彩平衡
             r=app.rSlider.Value;
             g=app.gSlider.Value;
             b=app.bSlider.Value;
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end        
           im= color_enhance(im,r,g,b);
           %显示新的图像矩阵
           imshow(im,'Parent',channel);
           if(channel==app.channel1im)
               app.images1=im;
           elseif(channel==app.channel2im)
               app.images2=im;
           elseif(channel==app.channel3im)
               app.images3=im;
           end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %进行灰度转化
            [im,channel]=channelchoice(app);
            if(channel==app.channel1im)
               im=app.images1;
           elseif(channel==app.channel2im)
               im=app.images2;
           elseif(channel==app.channel3im)
               im=app.images3;
            end   
           im= colortogray(im);
           %显示新的图像矩阵
           imshow(im,'Parent',channel);
           if(channel==app.channel1im)
               app.images1=im;
           elseif(channel==app.channel2im)
               app.images2=im;
           elseif(channel==app.channel3im)
               app.images3=im;
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '选择显示通道';
            app.channel1Button = uiradiobutton(app.ButtonGroup);
            app.channel1Button.Text = 'channel1';
            app.channel1Button.Value = true;
            app.channel2Button = uiradiobutton(app.ButtonGroup);
            app.channel2Button.Text = 'channel2';
            app.channel3Button = uiradiobutton(app.ButtonGroup);
            app.channel3Button.Text = 'channel3';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.channel1Tab = uitab(app.TabGroup);
            app.channel1Tab.Title = 'channel1';
            app.channel1im = uiaxes(app.channel1Tab);
            zlabel(app.channel1im, 'Z')
            app.channel1im.ButtonDownFcn = createCallbackFcn(app, @import_imgValueChanged, true);
            app.channel2Tab = uitab(app.TabGroup);
            app.channel2Tab.Title = 'channel2';
            app.channel2im = uiaxes(app.channel2Tab);
            zlabel(app.channel2im, 'Z')
            app.channel3Tab = uitab(app.TabGroup);
            app.channel3Tab.Title = 'channel3';
            app.channel3im = uiaxes(app.channel3Tab);
            zlabel(app.channel3im, 'Z')
            app.import_img = uibutton(app.UIFigure, 'state');
            app.import_img.ValueChangedFcn = createCallbackFcn(app, @import_imgValueChanged, true);
            app.import_img.Text = '读取图片';
            app.clear_img = uibutton(app.UIFigure, 'state');
            app.clear_img.ValueChangedFcn = createCallbackFcn(app, @clear_imgValueChanged, true);
            app.clear_img.Text = '清空图片';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup2);
            app.Tab1.Title = '点操作1';
            app.GammaButton = uibutton(app.Tab1, 'push');
            app.GammaButton.ButtonPushedFcn = createCallbackFcn(app, @GammaButtonPushed, true);
            app.GammaButton.Text = 'Gamma:预览';
            app.GammaSlider = uislider(app.Tab1);
            app.GammaSlider.Limits = [0 5];
            app.GammaSlider.MajorTicks = [0 1 2 3 4 5];
            app.GammaSlider.MajorTickLabels = {'0', '1', '2', '3', '4', '5'};
            app.GammaSlider.ValueChangedFcn = createCallbackFcn(app, @GammaSliderValueChanged, true);
            app.GammaSlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5];
            app.GammaSlider.Value = 1;
            app.LogButton = uibutton(app.Tab1, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Text = '对数变换：预览';
            app.LogSlider = uislider(app.Tab1);
            app.LogSlider.Limits = [0 10];
            app.LogSlider.MajorTicks = [0 2 4 6 8 10];
            app.LogSlider.ValueChangedFcn = createCallbackFcn(app, @LogSliderValueChanged, true);
            app.LogSlider.MinorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10];
            app.LogSlider.Value = 1;
            app.GammaTextAreaLabel = uilabel(app.Tab1);
            app.GammaTextAreaLabel.Text = 'Gamma值';
            app.GammaTextArea = uitextarea(app.Tab1);
            app.GammaTextArea.ValueChangedFcn = createCallbackFcn(app, @GammaTextAreaValueChanged, true);
            app.GammaTextArea.Value = {'1'};
            app.TextAreaLabel = uilabel(app.Tab1);
            app.TextAreaLabel.Text = '参数值';
            app.LogTextArea = uitextarea(app.Tab1);
            app.LogTextArea.Value = {'1'};
            app.GammaButton_2 = uibutton(app.Tab1, 'push');
            app.GammaButton_2.ButtonPushedFcn = createCallbackFcn(app, @GammaButton_2Pushed, true);
            app.GammaButton_2.Text = 'Gamma:保存';
            app.LogButton_2 = uibutton(app.Tab1, 'push');
            app.LogButton_2.ButtonPushedFcn = createCallbackFcn(app, @LogButton_2Pushed, true);
            app.LogButton_2.Text = '对数变换：保存';
            app.Tab2 = uitab(app.TabGroup2);
            app.Tab2.Title = '点操作2';
            app.Button1 = uibutton(app.Tab2, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = '图像求补：Do it!';
            app.DoitButton2 = uibutton(app.Tab2, 'push');
            app.DoitButton2.ButtonPushedFcn = createCallbackFcn(app, @DoitButton2Pushed, true);
            app.DoitButton2.Text = '亮度增加：预览';
            app.Label = uilabel(app.Tab2);
            app.Label.Text = '亮度参数';
            app.TextArea2 = uitextarea(app.Tab2);
            app.Slider2 = uislider(app.Tab2);
            app.Slider2.Limits = [-1 1];
            app.Slider2.MajorTicks = [-1 0 1];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Button3 = uibutton(app.Tab2, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed2, true);
            app.Button3.Text = '转为xyz颜色变换：Do it!';
            app.DoitButton2_2 = uibutton(app.Tab2, 'push');
            app.DoitButton2_2.ButtonPushedFcn = createCallbackFcn(app, @DoitButton2_2Pushed, true);
            app.DoitButton2_2.Text = '亮度增加：保存';
            app.Tab3 = uitab(app.TabGroup2);
            app.Tab3.Title = '点操作3';
            app.Button3_1 = uibutton(app.Tab3, 'push');
            app.Button3_1.ButtonPushedFcn = createCallbackFcn(app, @Button3_1Pushed, true);
            app.Button3_1.Text = '色彩调整：预览';
            app.rSliderLabel = uilabel(app.Tab3);
            app.rSliderLabel.Text = 'r通道';
            app.rSlider = uislider(app.Tab3);
            app.rSlider.Limits = [-1 1];
            app.gSliderLabel = uilabel(app.Tab3);
            app.gSliderLabel.Text = 'g通道';
            app.gSlider = uislider(app.Tab3);
            app.gSlider.Limits = [-1 1];
            app.bSliderLabel = uilabel(app.Tab3);
            app.bSliderLabel.Text = 'b通道';
            app.bSlider = uislider(app.Tab3);
            app.bSlider.Limits = [-1 1];
            app.Button3_2 = uibutton(app.Tab3, 'push');
            app.Button3_2.ButtonPushedFcn = createCallbackFcn(app, @Button3_2Pushed, true);
            app.Button3_2.Text = '色彩调整：保存';
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = '滤波1';
            app.Button4_1 = uibutton(app.Tab, 'push');
            app.Button4_1.ButtonPushedFcn = createCallbackFcn(app, @Button4_1Pushed, true);
            app.Button4_1.Text = '高斯滤波：Do it!';
            app.kernelSizeEditFieldLabel = uilabel(app.Tab);
            app.kernelSizeEditFieldLabel.Text = 'kernelSize';
            app.kernelSizeEditField = uieditfield(app.Tab, 'numeric');
            app.kernelSizeEditField.Limits = [0 100];
            app.sigmaEditFieldLabel = uilabel(app.Tab);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.Tab, 'numeric');
            app.Button4_2 = uibutton(app.Tab, 'push');
            app.Button4_2.ButtonPushedFcn = createCallbackFcn(app, @Button4_2Pushed, true);
            app.Button4_2.Text = '中值滤波：Do it!';
            app.Button4_3 = uibutton(app.Tab, 'push');
            app.Button4_3.ButtonPushedFcn = createCallbackFcn(app, @Button4_3Pushed, true);
            app.Button4_3.Text = '拉普拉斯滤波：Do it!';
            app.Button4_4 = uibutton(app.Tab, 'push');
            app.Button4_4.ButtonPushedFcn = createCallbackFcn(app, @Button4_4Pushed, true);
            app.Button4_4.Text = '高斯金字塔：Do it!';
            app.Button4_5 = uibutton(app.Tab, 'push');
            app.Button4_5.ButtonPushedFcn = createCallbackFcn(app, @Button4_5Pushed, true);
            app.Button4_5.Text = 'shift：Do it!';
            app.TextArea = uitextarea(app.Tab);
            app.TextArea.Value = {'Box滤波:'};
            app.Button4_6 = uibutton(app.Tab, 'push');
            app.Button4_6.ButtonPushedFcn = createCallbackFcn(app, @Button4_6Pushed, true);
            app.Button4_6.Text = 'shaperen：Do it!';
            app.Button4_7 = uibutton(app.Tab, 'push');
            app.Button4_7.ButtonPushedFcn = createCallbackFcn(app, @Button4_7Pushed, true);
            app.Button4_7.Text = 'sobel-edge：Do it!';
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = '滤波3';
            app.lowpassfilterButton = uibutton(app.Tab_2, 'push');
            app.lowpassfilterButton.ButtonPushedFcn = createCallbackFcn(app, @lowpassfilterButtonPushed, true);
            app.lowpassfilterButton.Text = '低通滤波';
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = '其他';
            app.Button = uibutton(app.Tab_3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '灰度图转化:预览';
            app.DoitButton_2 = uibutton(app.Tab_3, 'push');
            app.DoitButton_2.ButtonPushedFcn = createCallbackFcn(app, @DoitButton_2Pushed, true);
            app.DoitButton_2.Text = '直方图均衡化:Do it!';
            app.houghtransformbutton = uibutton(app.Tab_3, 'push');
            app.houghtransformbutton.ButtonPushedFcn = createCallbackFcn(app, @houghtransformbuttonButtonPushed, true);
            app.houghtransformbutton.Text = '霍夫变换';
            app.Button_2 = uibutton(app.Tab_3, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '灰度图转化:保存';
            app.saveimage = uibutton(app.UIFigure, 'push');
            app.saveimage.ButtonPushedFcn = createCallbackFcn(app, @saveimageButtonPushed, true);
            app.saveimage.Text = '图像保存';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end